classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GalvomirrorControlSystemUIFigure  matlab.ui.Figure
        GalvoMirrorPanel     matlab.ui.container.Panel
        y0EditField          matlab.ui.control.NumericEditField
        y0EditFieldLabel     matlab.ui.control.Label
        x0EditField          matlab.ui.control.NumericEditField
        x0EditFieldLabel     matlab.ui.control.Label
        AutoStopButton       matlab.ui.control.Button
        AutoStartButton      matlab.ui.control.Button
        RyEditField          matlab.ui.control.NumericEditField
        yEditField_2Label_2  matlab.ui.control.Label
        RxEditField          matlab.ui.control.NumericEditField
        xEditField_2Label_2  matlab.ui.control.Label
        dyEditField          matlab.ui.control.NumericEditField
        dyLabel              matlab.ui.control.Label
        dxEditField          matlab.ui.control.NumericEditField
        dxLabel              matlab.ui.control.Label
        ManualGotoButton     matlab.ui.control.Button
        yEditField           matlab.ui.control.NumericEditField
        yEditFieldLabel      matlab.ui.control.Label
        xEditField           matlab.ui.control.NumericEditField
        xEditFieldLabel      matlab.ui.control.Label
        AndorCameraPanel     matlab.ui.container.Panel
        CaptureButton        matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManualGotoButton
        function ManualGotoButtonPushed(app, event)
            % Call the ipconfig command and capture its output
            [status, cmdout] = system('ipconfig');
            % Display the output in a message dialog
            uialert(app.GalvomirrorControlSystemUIFigure, cmdout, 'ipconfig Output');
       
            
            %res = pyrunfile("hello.py","z",x=3,y=2);
            %app.GalvomirrorLabel.Text=string(string(res));            
            %py.math.sqrt(43)   
            %pyrun(["greeting = 'hello'", "print(greeting)"])
            %pyrunfile("hello.py")
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            disp('Andor Single Scan Start ...');
            [rc] = AT_InitialiseLibrary();
            AT_CheckError(rc);
            [rc,hndl] = AT_Open(0);
            AT_CheckError(rc);
            disp('Camera initialized');
            [rc] = AT_SetFloat(hndl,'ExposureTime',0.01);
            AT_CheckWarning(rc);
            [rc] = AT_SetEnumString(hndl,'CycleMode','Fixed');
            AT_CheckWarning(rc);
            [rc] = AT_SetEnumString(hndl,'TriggerMode','Internal');
            AT_CheckWarning(rc);
            [rc] = AT_SetEnumString(hndl,'SimplePreAmpGainControl','16-bit (low noise & high well capacity)');
            AT_CheckWarning(rc);
            [rc] = AT_SetEnumString(hndl,'PixelEncoding','Mono16');
            AT_CheckWarning(rc);
            [rc] = AT_SetInt(hndl,'FrameCount',1);
            AT_CheckWarning(rc);
            [rc,imagesize] = AT_GetInt(hndl,'ImageSizeBytes');
            AT_CheckWarning(rc);
            [rc,height] = AT_GetInt(hndl,'AOIHeight');
            AT_CheckWarning(rc);
            [rc,width] = AT_GetInt(hndl,'AOIWidth');  
            AT_CheckWarning(rc);
            [rc,stride] = AT_GetInt(hndl,'AOIStride'); 
            AT_CheckWarning(rc);
            [rc] = AT_QueueBuffer(hndl,imagesize);
            AT_CheckWarning(rc);
            disp('Starting acquisition...');
            [rc] = AT_Command(hndl,'AcquisitionStart');
            AT_CheckWarning(rc);
            [rc,buf] = AT_WaitBuffer(hndl,1000);
            AT_CheckWarning(rc);
            [rc,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
            AT_CheckWarning(rc);
            %imagesc(buf2);
            imagesc(buf2, 'Parent', app.UIAxes);
            disp('Acquisition complete');
            [rc] = AT_Command(hndl,'AcquisitionStop');
            AT_CheckWarning(rc);
            [rc] = AT_Flush(hndl);
            AT_CheckWarning(rc);
            [rc] = AT_Close(hndl);
            AT_CheckWarning(rc);
            [rc] = AT_FinaliseLibrary();
            AT_CheckWarning(rc);
            disp('Camera shutdown');
        end

        % Callback function
        function CameraSwitchValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GalvomirrorControlSystemUIFigure = uifigure('Visible', 'off');
            app.GalvomirrorControlSystemUIFigure.Name = 'Galvomirror Control System';
            app.GalvomirrorControlSystemUIFigure.Icon = fullfile(pathToMLAPP, 'galvomirror.png');
            app.AndorCameraPanel = uipanel(app.GalvomirrorControlSystemUIFigure);
            app.AndorCameraPanel.Title = 'Andor Camera';
            app.UIAxes = uiaxes(app.AndorCameraPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.CaptureButton = uibutton(app.AndorCameraPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Icon = fullfile(pathToMLAPP, 'camera.png');
            app.CaptureButton.Text = 'Capture';
            app.GalvoMirrorPanel = uipanel(app.GalvomirrorControlSystemUIFigure);
            app.GalvoMirrorPanel.Title = 'Galvo Mirror';
            app.xEditFieldLabel = uilabel(app.GalvoMirrorPanel);
            app.xEditFieldLabel.Text = 'x:';
            app.xEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.GalvoMirrorPanel);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.ManualGotoButton = uibutton(app.GalvoMirrorPanel, 'push');
            app.ManualGotoButton.ButtonPushedFcn = createCallbackFcn(app, @ManualGotoButtonPushed, true);
            app.ManualGotoButton.Text = 'Manual Goto';
            app.dxLabel = uilabel(app.GalvoMirrorPanel);
            app.dxLabel.Text = 'dx:';
            app.dxEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.dyLabel = uilabel(app.GalvoMirrorPanel);
            app.dyLabel.Text = 'dy:';
            app.dyEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.xEditField_2Label_2 = uilabel(app.GalvoMirrorPanel);
            app.xEditField_2Label_2.Text = 'Rx:';
            app.RxEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.yEditField_2Label_2 = uilabel(app.GalvoMirrorPanel);
            app.yEditField_2Label_2.Text = 'Ry:';
            app.RyEditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.AutoStartButton = uibutton(app.GalvoMirrorPanel, 'push');
            app.AutoStartButton.Text = 'Auto Start';
            app.AutoStopButton = uibutton(app.GalvoMirrorPanel, 'push');
            app.AutoStopButton.Text = 'Auto Stop';
            app.x0EditFieldLabel = uilabel(app.GalvoMirrorPanel);
            app.x0EditFieldLabel.Text = 'x0:';
            app.x0EditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            app.y0EditFieldLabel = uilabel(app.GalvoMirrorPanel);
            app.y0EditFieldLabel.Text = 'y0:';
            app.y0EditField = uieditfield(app.GalvoMirrorPanel, 'numeric');
            % Show the figure after all components are created
            app.GalvomirrorControlSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GalvomirrorControlSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GalvomirrorControlSystemUIFigure)
        end
    end
end